classdef gui_draw_path < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CarControlInterfaceUIFigure  matlab.ui.Figure
        ClearRxButton                matlab.ui.control.Button
        UpdateRxButton               matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        PRINTButton                  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        xEditField                   matlab.ui.control.EditField
        xEditFieldLabel              matlab.ui.control.Label
        w_maxEditField               matlab.ui.control.EditField
        w_maxEditFieldLabel          matlab.ui.control.Label
        dEditField                   matlab.ui.control.EditField
        dEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.EditField
        aEditFieldLabel              matlab.ui.control.Label
        UpdateButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        DrawpathButton               matlab.ui.control.Button
        LOADButton                   matlab.ui.control.Button
        CommandsPanel                matlab.ui.container.Panel
        OffPlotingButton             matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        PrintButton                  matlab.ui.control.Button
        UploadButton                 matlab.ui.control.Button
        wBEditField                  matlab.ui.control.EditField
        wBEditFieldLabel             matlab.ui.control.Label
        wAEditField                  matlab.ui.control.EditField
        wAEditFieldLabel             matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        REditField                   matlab.ui.control.EditField
        REditFieldLabel              matlab.ui.control.Label
        wEditField                   matlab.ui.control.EditField
        wEditFieldLabel              matlab.ui.control.Label
        MotorspeedsButton            matlab.ui.control.Button
        OnPlotingButton              matlab.ui.control.Button
        CircleButton                 matlab.ui.control.Button
        LineButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        BluetoothConnectionPanel     matlab.ui.container.Panel
        DisconnectButton             matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        SpeedEditField               matlab.ui.control.EditField
        SpeedEditFieldLabel          matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        PortEditField                matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        PingButton                   matlab.ui.control.Button
    end

    
    properties (Access = public) % Description
        MyTimer = 0;
        message="";
    end
    
    methods (Access = public)

        function TimerCallback(app, obj, event)  
            s_p = evalin('base','s_p_workspace');
            recv_message=function_read_serial_string_automatic_s_p(s_p);
            app.message=strcat(app.message,recv_message);
            app.TextArea.Value=app.message;
        end

        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyTimer = timer('Period',1,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
        end

        % Button pushed function: DrawpathButton
        function DrawpathButtonPushed(app, event)

            a = evalin('base','a_workspace');
            d = evalin('base','d_workspace');
            max_rev_s=evalin('base','max_rev_s_workspace');
            omega_max_mov=max_rev_s*2*pi; %rad/s
            
            scale_path = evalin('base','scale_path_workspace');
            disp('Click the mouse wherever in the figure; press ENTER when finished.');
            mousePointCoords = ginput*scale_path;
            close
            
            [w1_vector,w2_vector,dt_vector,x0,y0,psi0,estimated_position,phi_pos] = function_get_references_from_route(mousePointCoords, omega_max_mov,a,d);
            w1_vector_rev_s=w1_vector/(2*pi);
            w2_vector_rev_s=w2_vector/(2*pi);

            
            total_time = sum( dt_vector , 'all' )*1.1;
            function_kinematic_model_animation(0.005,total_time,a,d,x0,y0,psi0,estimated_position,w1_vector,w2_vector,dt_vector,false)
            
            T = array2table([w1_vector_rev_s',w2_vector_rev_s',dt_vector'],'VariableNames',{'w1','w2','dt'});
            assignin('base','T_workspace',T)
 
            command_w1_char_array=function_convert_vectordata_to_char_array('4',w1_vector_rev_s,2);
            assignin('base','command_w1_char_array_workspace',command_w1_char_array);
            %command_w1_char_array = evalin('base','command_w1_char_array_workspace');

            command_w2_char_array=function_convert_vectordata_to_char_array('5',w2_vector_rev_s,2);
            assignin('base','command_w2_char_array_workspace',command_w2_char_array);
            %command_w2_char_array = evalin('base','command_w2_char_array_workspace');

            command_dt_vector_array=function_convert_vectordata_to_char_array('6',dt_vector*1000,1);
            assignin('base','command_dt_vector_array_workspace',command_dt_vector_array);
            %command_dt_vector_array = evalin('base','command_dt_vector_array_workspace');

            %app.UITable.DisplayData;
            %app.ArrayPathLabel.Text=string(w1_vector);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            T = evalin('base','T_workspace');
            app.UITable.Data=T;
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            
            close all;
            clear all;

            scale_path=0.5; %meter
            assignin('base','scale_path_workspace',scale_path);
            %scale_path = evalin('base','scale_path_workspace');
            
            a=0.0325; %meter
            assignin('base','a_workspace',a);
            %a = evalin('base','a_workspace');

            d=0.0725; %meter
            assignin('base','d_workspace',d);
            %d = evalin('base','d_workspace');

            max_rev_s=1.5; %rev/s
            assignin('base','max_rev_s_workspace',max_rev_s);
            %max_rev_s = evalin('base','max_rev_s_workspace');
            
            w_line=1.5; % cm
            assignin('base','w_line_workspace',w_line);
            %w_line = evalin('base','w_line_workspace');
                        
            circle_r=15; % cm
            assignin('base','circle_r_workspace',circle_r);
            %circle_r = evalin('base','circle_r_workspace');
            
            circle_perc=70; % %
            assignin('base','circle_perc_workspace',circle_perc);
            %circle_perc = evalin('base','circle_perc_workspace');
            

            wA=1.5; % cm
            assignin('base','wA_workspace',wA);
            %wA = evalin('base','wA_workspace');
            
            wB=1.5; % %
            assignin('base','wB_workspace',wB);
            %wB = evalin('base','wB_workspace');

            port="COM6";
            assignin('base','port_workspace',port);
            %port = evalin('base','port_workspace');

            speed=115200;
            assignin('base','speed_workspace',speed);
            %speed = str2double(evalin('base','speed_workspace'));

            c_stop_motors='$0;';
            assignin('base','c_stop_motors_workspace',c_stop_motors);
            %c_stop_motors = evalin('base','c_stop_motors_workspace');

            c_enable_ploting='$3:1:1;';
            assignin('base','c_enable_ploting_workspace',c_enable_ploting);
            %c_enable_ploting = evalin('base','c_enable_ploting_workspace');

            c_disable_ploting='$3:1:0;';
            assignin('base','c_disable_ploting_workspace',c_disable_ploting);
            %c_disable_ploting = evalin('base','c_disable_ploting_workspace');

            c_start_track='$7;';
            assignin('base','c_start_track_workspace',c_start_track);
            %c_start_track = evalin('base','c_start_track_workspace');

            c_print_track='$8;';
            assignin('base','c_print_track_workspace',c_print_track);
            %c_print_track = evalin('base','c_print_track_workspace');

            c_ping='$10;';
            assignin('base','c_ping_workspace',c_ping);
            %c_ping = evalin('base','c_ping_workspace');

        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            assignin('base','a_workspace',str2num(value));         
        end

        % Button pushed function: PRINTButton
        function PRINTButtonPushed(app, event)
            scale_path = evalin('base','scale_path_workspace');
            app.xEditField.Value=string(scale_path);
            
            a = evalin('base','a_workspace');
            app.aEditField.Value=string(a);

            d = evalin('base','d_workspace');
            app.dEditField.Value=string(d);
            
            max_rev_s = evalin('base','max_rev_s_workspace');
            app.w_maxEditField.Value=string(max_rev_s);
            
            w_line = evalin('base','w_line_workspace');
            app.wEditField.Value=string(w_line);

            circle_r = evalin('base','circle_r_workspace');
            app.REditField.Value=string(circle_r);

            circle_perc = evalin('base','circle_perc_workspace');
            app.EditField.Value=string(circle_perc);

            wA = evalin('base','wA_workspace');
            app.wAEditField.Value=string(wA);

            wB = evalin('base','wB_workspace');
            app.wBEditField.Value=string(wB);

            port = evalin('base','port_workspace');
            app.PortEditField.Value=string(port);

            speed = evalin('base','speed_workspace');
            app.SpeedEditField.Value=string(speed);

        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            app.StatusLabel.Text="...";
            s_p = evalin('base','s_p_workspace');
            c_ping = evalin('base','c_ping_workspace');
            number_bytes_rx=4;
            string_rx = function_write_read_string_s_p(s_p, c_ping, number_bytes_rx);
            function_send_serial_char_array_s_p(s_p, [13,10]);
            app.StatusLabel.Text=string_rx;
            pause(1);
            app.StatusLabel.Text="...";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            c_stop_motors = evalin('base','c_stop_motors_workspace');

            function_send_serial_char_array_s_p(s_p, c_stop_motors);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            w_line = evalin('base','w_line_workspace');

            c_line_motors = function_convert_vectordata_to_char_array('1',[w_line],1);

            function_send_serial_char_array_s_p(s_p, c_line_motors);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: CircleButton
        function CircleButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            circle_r = evalin('base','circle_r_workspace');
            circle_perc = evalin('base','circle_perc_workspace');
            c_circle_motors = function_convert_vectordata_to_char_array('2',[circle_r,circle_perc],1);

            function_send_serial_char_array_s_p(s_p, c_circle_motors);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: OnPlotingButton
        function OnPlotingButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            c_enable_ploting = evalin('base','c_enable_ploting_workspace');
            function_send_serial_char_array_s_p(s_p, c_enable_ploting);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: MotorspeedsButton
        function MotorspeedsButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            wA = evalin('base','wA_workspace');
            wB = evalin('base','wB_workspace');
            c_motor_speeds = function_convert_vectordata_to_char_array('9',[wA,wB],2);

            function_send_serial_char_array_s_p(s_p, c_motor_speeds);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: OffPlotingButton
        function OffPlotingButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            c_disable_ploting = evalin('base','c_disable_ploting_workspace');
            function_send_serial_char_array_s_p(s_p, c_disable_ploting);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            command_w1_char_array = evalin('base','command_w1_char_array_workspace');
            command_w2_char_array = evalin('base','command_w2_char_array_workspace');
            command_dt_vector_array = evalin('base','command_dt_vector_array_workspace');

            function_send_serial_char_array_s_p(s_p, command_w1_char_array);
            function_send_serial_char_array_s_p(s_p, [13,10]);
            function_send_serial_char_array_s_p(s_p, command_w2_char_array);
            function_send_serial_char_array_s_p(s_p, [13,10]);
            function_send_serial_char_array_s_p(s_p, command_dt_vector_array);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            c_print_track = evalin('base','c_print_track_workspace');

            function_send_serial_char_array_s_p(s_p, c_print_track);
            function_send_serial_char_array_s_p(s_p, [13,10]);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        s_p = evalin('base','s_p_workspace');
        c_start_track = evalin('base','c_start_track_workspace');

        function_send_serial_char_array_s_p(s_p, c_start_track);
        function_send_serial_char_array_s_p(s_p, [13,10]);            
        end

        % Button pushed function: UpdateRxButton
        function UpdateRxButtonPushed(app, event)
            s_p = evalin('base','s_p_workspace');
            recv_message=function_read_serial_string_automatic_s_p(s_p);
            app.message=strcat(app.message,recv_message);
            app.TextArea.Value=app.message;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           port = evalin('base','port_workspace');
           speed = evalin('base','speed_workspace');
           s_p=serialport(port, speed,"Timeout",5);
           assignin('base','s_p_workspace',s_p);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            evalin( 'base', 'clear s_p_workspace' )            
        end

        % Button pushed function: ClearRxButton
        function ClearRxButtonPushed(app, event)
            app.message="";
            app.TextArea.Value=app.message;
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            assignin('base','w_line_workspace',str2num(value));
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            assignin('base','d_workspace',str2num(value));
        end

        % Value changed function: w_maxEditField
        function w_maxEditFieldValueChanged(app, event)
            value = app.w_maxEditField.Value;
            assignin('base','max_rev_s_workspace',str2num(value));
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            assignin('base','circle_r_workspace',str2num(value));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            assignin('base','circle_perc_workspace',str2num(value));
        end

        % Value changed function: wAEditField
        function wAEditFieldValueChanged(app, event)
            value = app.wAEditField.Value;
            assignin('base','wA_workspace',str2num(value));
        end

        % Value changed function: wBEditField
        function wBEditFieldValueChanged(app, event)
            value = app.wBEditField.Value;
            assignin('base','wB_workspace',str2num(value));
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            assignin('base','port_workspace',value);
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = app.SpeedEditField.Value;
            assignin('base','speed_workspace',str2num(value));
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            assignin('base','scale_path_workspace',str2num(value));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CarControlInterfaceUIFigure = uifigure('Visible', 'off');
            app.CarControlInterfaceUIFigure.Name = 'Car Control Interface';
            app.CarControlInterfaceUIFigure.Icon = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.BluetoothConnectionPanel = uipanel(app.CarControlInterfaceUIFigure);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.PingButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Text = 'Ping';
            app.PortEditFieldLabel = uilabel(app.BluetoothConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.PortEditField = uieditfield(app.BluetoothConnectionPanel, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.StatusLabel = uilabel(app.BluetoothConnectionPanel);
            app.StatusLabel.Text = 'Status';
            app.SpeedEditFieldLabel = uilabel(app.BluetoothConnectionPanel);
            app.SpeedEditFieldLabel.Text = 'Speed:';
            app.SpeedEditField = uieditfield(app.BluetoothConnectionPanel, 'text');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.CommandsPanel = uipanel(app.CarControlInterfaceUIFigure);
            app.CommandsPanel.Title = 'Commands';
            app.StopButton = uibutton(app.CommandsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LineButton = uibutton(app.CommandsPanel, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = 'Line';
            app.CircleButton = uibutton(app.CommandsPanel, 'push');
            app.CircleButton.ButtonPushedFcn = createCallbackFcn(app, @CircleButtonPushed, true);
            app.CircleButton.Text = 'Circle';
            app.OnPlotingButton = uibutton(app.CommandsPanel, 'push');
            app.OnPlotingButton.ButtonPushedFcn = createCallbackFcn(app, @OnPlotingButtonPushed, true);
            app.OnPlotingButton.Text = 'On Ploting';
            app.MotorspeedsButton = uibutton(app.CommandsPanel, 'push');
            app.MotorspeedsButton.ButtonPushedFcn = createCallbackFcn(app, @MotorspeedsButtonPushed, true);
            app.MotorspeedsButton.Text = 'Motor speeds';
            app.wEditFieldLabel = uilabel(app.CommandsPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.CommandsPanel, 'text');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.REditFieldLabel = uilabel(app.CommandsPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.CommandsPanel, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.CommandsPanel);
            app.EditFieldLabel.Text = '%';
            app.EditField = uieditfield(app.CommandsPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.wAEditFieldLabel = uilabel(app.CommandsPanel);
            app.wAEditFieldLabel.Text = 'wA';
            app.wAEditField = uieditfield(app.CommandsPanel, 'text');
            app.wAEditField.ValueChangedFcn = createCallbackFcn(app, @wAEditFieldValueChanged, true);
            app.wBEditFieldLabel = uilabel(app.CommandsPanel);
            app.wBEditFieldLabel.Text = 'wB';
            app.wBEditField = uieditfield(app.CommandsPanel, 'text');
            app.wBEditField.ValueChangedFcn = createCallbackFcn(app, @wBEditFieldValueChanged, true);
            app.UploadButton = uibutton(app.CommandsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.PrintButton = uibutton(app.CommandsPanel, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.StartButton = uibutton(app.CommandsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OffPlotingButton = uibutton(app.CommandsPanel, 'push');
            app.OffPlotingButton.ButtonPushedFcn = createCallbackFcn(app, @OffPlotingButtonPushed, true);
            app.OffPlotingButton.Text = 'Off Ploting';
            app.LOADButton = uibutton(app.CarControlInterfaceUIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.Panel = uipanel(app.CarControlInterfaceUIFigure);
            app.Panel.Title = 'Panel';
            app.DrawpathButton = uibutton(app.Panel, 'push');
            app.DrawpathButton.ButtonPushedFcn = createCallbackFcn(app, @DrawpathButtonPushed, true);
            app.DrawpathButton.Text = 'Draw path';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'w1'; 'w2'; 'dt'};
            app.UITable.RowName = {};
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.Panel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.w_maxEditFieldLabel = uilabel(app.Panel);
            app.w_maxEditFieldLabel.Text = 'w_max';
            app.w_maxEditField = uieditfield(app.Panel, 'text');
            app.w_maxEditField.ValueChangedFcn = createCallbackFcn(app, @w_maxEditFieldValueChanged, true);
            app.xEditFieldLabel = uilabel(app.Panel);
            app.xEditFieldLabel.Text = '1:x';
            app.xEditField = uieditfield(app.Panel, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.PRINTButton = uibutton(app.CarControlInterfaceUIFigure, 'push');
            app.PRINTButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTButtonPushed, true);
            app.PRINTButton.Text = 'PRINT';
            app.TextArea = uitextarea(app.CarControlInterfaceUIFigure);
            app.UpdateRxButton = uibutton(app.CarControlInterfaceUIFigure, 'push');
            app.UpdateRxButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRxButtonPushed, true);
            app.UpdateRxButton.Text = 'Update Rx';
            app.ClearRxButton = uibutton(app.CarControlInterfaceUIFigure, 'push');
            app.ClearRxButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRxButtonPushed, true);
            app.ClearRxButton.Text = 'Clear Rx';
            % Show the figure after all components are created
            app.CarControlInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_draw_path

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CarControlInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CarControlInterfaceUIFigure)
        end
    end
end